classdef interactive_POC_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConceptualPOCModelUIFigure      matlab.ui.Figure
        SelectLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ForcingparametersTab            matlab.ui.container.Tab
        AWtransportSvSlider             matlab.ui.control.Slider
        AWtransportSvLabel              matlab.ui.control.Label
        HeatlossTWSlider                matlab.ui.control.Slider
        HeatlossTWLabel                 matlab.ui.control.Label
        FreshwaterlossmSvSlider         matlab.ui.control.Slider
        FreshwaterlossmSvLabel          matlab.ui.control.Label
        StaticparametersTab             matlab.ui.container.Tab
        EquationofstateSwitch           matlab.ui.control.Switch
        EquationofstateSwitchLabel      matlab.ui.control.Label
        FreezingtemperatureSwitch       matlab.ui.control.Switch
        FreezingtemperatureSwitchLabel  matlab.ui.control.Label
        ReportresultsButton             matlab.ui.control.Button
        NnormalizedGauge                matlab.ui.control.LinearGauge
        NnormalizedGaugeLabel           matlab.ui.control.Label
        SeeFluxResultsDiagramLabel      matlab.ui.control.Label
        AWsalinitygkgGaugeLabel_2       matlab.ui.control.Label
        OWsalinitygkgGauge              matlab.ui.control.Gauge
        OWtemperatureoCGaugeLabel       matlab.ui.control.Label
        OWtemperatureoCGauge            matlab.ui.control.Gauge
        SelectAWaWandPWpropertiesontheinteractiveTSdiagramLabel  matlab.ui.control.Label
        ConceptualPolarOverturningCirculationModelLabel  matlab.ui.control.Label
        ResultsLabel                    matlab.ui.control.Label
        SelectParametersLabel           matlab.ui.control.Label
        SWsalinitygkgGauge              matlab.ui.control.LinearGauge
        SWsalinitygkgGaugeLabel         matlab.ui.control.Label
        aWmixingfractionGauge           matlab.ui.control.NinetyDegreeGauge
        aWmixingfractionGaugeLabel      matlab.ui.control.Label
        aWtemperatureoCGauge            matlab.ui.control.Gauge
        aWtemperatureoCGaugeLabel       matlab.ui.control.Label
        aWsalinitygkgGauge              matlab.ui.control.Gauge
        aWsalinitygkgGaugeLabel         matlab.ui.control.Label
        PWsalinitygkgGauge              matlab.ui.control.LinearGauge
        PWsalinitygkgGaugeLabel         matlab.ui.control.Label
        SolutionLamp                    matlab.ui.control.Lamp
        SolutionLampLabel               matlab.ui.control.Label
        EntrainmentGauge                matlab.ui.control.SemicircularGauge
        EntrainmentGaugeLabel           matlab.ui.control.Label
        SolvemodelButton                matlab.ui.control.Button
        AWsalinitygkgGauge              matlab.ui.control.Gauge
        AWsalinitygkgGaugeLabel         matlab.ui.control.Label
        SelectDropDown                  matlab.ui.control.DropDown
        SelectDropDownLabel             matlab.ui.control.Label
        AWtemperatureoCGauge            matlab.ui.control.Gauge
        AWtemperatureoCGaugeLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        model       % Structure containing the model data.
        figs        % Structure to hold the figure handles.
        solution    % Valid solution flag
        recompute   % Need to resolve flag
    end
    
    methods (Access = public)
        
        function app = Update_display(app)
            if(app.solution)
                % Update lamps
                app.SolutionLamp.Color = 'green' ;
                app.SolvemodelButton.BackgroundColor = 'green' ;
                
                % Update gauges
                app.AWtemperatureoCGauge.Limits = app.model.base_case.app.T1_lims ;
                app.AWtemperatureoCGauge.Value = app.model.dynamic_parameters.T_1 ;
                app.AWsalinitygkgGauge.Limits = app.model.base_case.app.S1_lims ;
                app.AWsalinitygkgGauge.Value = app.model.dynamic_parameters.S_1 ;
                app.EntrainmentGauge.Value = app.model.sens.Phican ;
                app.PWsalinitygkgGauge.Value = app.model.dynamic_parameters.S_2 ;
                app.aWmixingfractionGauge.Value = app.model.dynamic_parameters.phi ;
                app.aWtemperatureoCGauge.Value = app.model.dynamic_parameters.T_a ;
                app.aWsalinitygkgGauge.Value = app.model.dynamic_parameters.S_a ;
                app.SWsalinitygkgGauge.Value = app.model.sens.S_scan ;
                app.OWtemperatureoCGauge.Value = app.model.sens.T_3can ;
                app.OWsalinitygkgGauge.Value = app.model.sens.S_3can ;
                
                % Update sliders
                app.AWtransportSvSlider.Limits = app.model.base_case.app.U1_lims ;
                app.AWtransportSvSlider.Value = app.model.dynamic_parameters.U_1/1e6 ;
                app.HeatlossTWSlider.Limits = app.model.base_case.app.Q_lims ;
                app.HeatlossTWSlider.Value = app.model.dynamic_parameters.Q./1e12 ;
                app.FreshwaterlossmSvSlider.Limits = app.model.base_case.app.F_lims ;
                app.FreshwaterlossmSvSlider.Value = app.model.dynamic_parameters.F./1e6 ;
                app.NnormalizedGauge.Value = app.model.theory.Nstar_norm ;
                
                % Update switches
                app.FreezingtemperatureSwitch.Value = app.model.static_fns.T_freezing_flag ;
                app.EquationofstateSwitch.Value = app.model.static_fns.EOS_flag ;
                
                % Update figures
                Update_fluxes_figure(app) ;
                Update_TS_figure(app) ;
            else
                app.SolutionLamp.Color = 'red' ;
            end % if
            drawnow
        end
        
        function plot_interactive_TS_diagram(app)
            
            % Setup
            dragging = [];
            orPos = [];
            
            %% Make Interactive TS diagram with theoretical constraints
            objects = plot_POC_TS_diagram(app.model) ;
            objects.p_AW.ButtonDownFcn = @dragObject ;
            objects.p_PW.ButtonDownFcn = @dragObject ;
            objects.p_aW.ButtonDownFcn = @dragObject ;
            set(get(gca,'Title'),'String','Adjust AW, PW, aW Properties') ;
            set(gcf,'WindowButtonUpFcn',@dropObject,'units','normalized','WindowButtonMotionFcn',@moveObject,'Visible','on') ;
            
            if(app.recompute)
                delete(objects.p_OW) ;
                delete(objects.p_SW) ;
                delete(objects.p_OW_vals) ;
                delete(objects.p_SW_vals) ;
                app.SolutionLamp.Color = [1 0.5 0] ;
            end % if
            
            %% Interactive functions
            % These are NESTED functions, so they can see the calling functions
            % variables, which is important!
            
            function dragObject(hObject,~)
                dragging = hObject;
                orPos = get(gca,'CurrentPoint');
                app.recompute = 1 ;
                app.SolutionLamp.Color = [1 0.5 0] ;
                delete(objects.p_OW) ;
                delete(objects.p_SW) ;
                delete(objects.p_OW_vals) ;
                delete(objects.p_SW_vals) ;
            end
            
            function dropObject(~,~)
                if ~isempty(dragging)
                    newPos = get(gca,'CurrentPoint');
                    posDiff = newPos - orPos;
                    set(dragging,'XData',get(dragging,'Xdata') + posDiff(1,1));
                    set(dragging,'YData',get(dragging,'Ydata') + posDiff(1,2));
                    set(gca,'selected','on') ;
                    dragging = [];
                end
            end
            
            function moveObject(hObject,~)
                if ~isempty(dragging)
                    newPos = get(gca,'CurrentPoint');
                    posDiff = newPos - orPos;
                    orPos = newPos;
                    new_S = get(dragging,'Xdata') + posDiff(1,1) ;
                    new_T = get(dragging,'Ydata') + posDiff(1,2) ;
                    
                    old_phi = app.model.dynamic_parameters.phi ;
                    new_phi = old_phi ;
                    old_AWS = objects.p_AW.XData ;
                    old_AWT = objects.p_AW.YData ;
                    old_PWS = objects.p_PW.XData ;
                    old_PWT = objects.p_PW.YData ;
                    old_aWS = objects.p_aW.XData ;
                    old_aWT = objects.p_aW.YData ;
                    
                    new_AWS = old_AWS ;
                    new_AWT = old_AWT ;
                    new_PWS = old_PWS ;
                    new_PWT = old_PWT ;
                    new_aWS = old_aWS ;
                    new_aWT = old_aWT ;
                    
                    switch(hObject.CurrentObject.DisplayName)
                        case 'AW'
                            new_AWS = new_S ;
                            new_AWT = max(new_T,app.model.static_fns.T_freezing(new_AWS,app.model.geophysical_parameters.pressure)) ;
                            app.model.dynamic_parameters.original_S_2 = compute_max_PW_salinity(new_AWT,new_AWS,app.model.geophysical_parameters,app.model.static_parameters,app.model.static_fns) ;
                            
                            % Also update PW if necessary
                            new_PWS = min(new_PWS,app.model.dynamic_parameters.original_S_2) ;
                            new_PWT = max(app.model.static_fns.T_freezing(new_PWS,app.model.geophysical_parameters.pressure)) ;
                            
                            % Also update aW
                            new_aWS = new_PWS*new_phi + new_AWS*(1-new_phi) ;
                            new_aWT = new_PWT*new_phi + new_AWT*(1-new_phi) ;
                            
                        case 'PW'
                            new_PWS = min(new_S,app.model.dynamic_parameters.original_S_2) ;
                            new_PWT = max(app.model.static_fns.T_freezing(new_S,app.model.geophysical_parameters.pressure)) ;
                            
                            % Also update aW
                            new_aWS = new_PWS*new_phi + app.model.dynamic_parameters.S_1*(1-new_phi) ;
                            new_aWT = new_PWT*new_phi + app.model.dynamic_parameters.T_1*(1-new_phi) ;
                            
                        case 'aW'
                            new_aWT = min(new_T,app.model.dynamic_parameters.T_1) ;
                            new_aWT = max(new_aWT,app.model.static_fns.T_freezing(app.model.dynamic_parameters.S_2,app.model.geophysical_parameters.pressure)) ;
                            new_aWS = min(new_S,app.model.static_fns.S_from_rho(app.model.dynamic_parameters.rh1,new_aWT,app.model.geophysical_parameters.pressure)) ;
                            new_aWT = max(new_aWT,app.model.static_fns.T_freezing(new_aWS,app.model.geophysical_parameters.pressure)) ;
                            
                            % Also update PW
                            S_2     = @(phi) (new_aWS + (phi - 1)*app.model.dynamic_parameters.S_1)/phi ;
                            Tf2     = @(phi) (new_aWT + (phi - 1)*app.model.dynamic_parameters.T_1)/phi ;
                            bnd_fn  = @(phi) (Tf2(phi) - app.model.static_fns.T_freezing(S_2(phi),app.model.geophysical_parameters.pressure))^2 ;
                            new_phi = fminbnd(bnd_fn,0,1) ;
                            
                            new_PWS = S_2(new_phi) ;
                            new_PWT = Tf2(new_phi) ;
                            
                            % Ensure that PW salinity can't exceed the maximum
                            % value.
                            new_PWS = min(new_PWS,app.model.dynamic_parameters.original_S_2) ;
                            
                            % Also update aW
                            new_aWS = new_PWS*new_phi + app.model.dynamic_parameters.S_1*(1-new_phi) ;
                            new_aWT = new_PWT*new_phi + app.model.dynamic_parameters.T_1*(1-new_phi) ;
                            
                    end % switch
                    
                    % Update graphics objects and data structure
                    app.model.dynamic_parameters.phi = new_phi ;
                    app.model.base_case.T_1          = new_AWT ;
                    app.model.base_case.S_1          = new_AWS ;
                    app.model.dynamic_parameters.T_1 = new_AWT ;
                    app.model.dynamic_parameters.S_1 = new_AWS ;
                    app.model.dynamic_parameters.T_1 = new_AWT ;
                    app.model.dynamic_parameters.rh1 = app.model.static_fns.rho(new_AWS,new_AWT,app.model.geophysical_parameters.pressure) ;
                    app.model.dynamic_parameters.S_2 = new_PWS ;
                    app.model.dynamic_parameters.Tf2 = new_PWT ;
                    app.model.dynamic_parameters.S_a = new_aWS ;
                    app.model.dynamic_parameters.T_a = new_aWT ;
                    
                    % Plot
                    set(objects.p_AW,'XData',new_AWS) ;
                    set(objects.p_AW,'YData',new_AWT) ;
                    set(objects.p_PW,'XData',new_PWS) ;
                    set(objects.p_PW,'YData',new_PWT) ;
                    set(objects.p_aW,'XData',new_aWS) ;
                    set(objects.p_aW,'YData',new_aWT) ;
                    
                    % Update lines and patches from theory:
                    app.model.theory = compute_POC_theory(app.model.dynamic_parameters,app.model.static_parameters,app.model.geophysical_parameters,app.model.static_fns) ;
                    set(objects.p_theory2,'XData',app.model.theory.S3s) ;
                    set(objects.p_theory2,'YData',app.model.theory.U_2_max.vals) ;
                    set(objects.p_theoryi,'XData',app.model.theory.S3s) ;
                    set(objects.p_theoryi,'YData',app.model.theory.U_i_max.vals) ;
                    set(objects.p1       ,'XData',app.model.theory.patch_data.S1) ;
                    set(objects.p1       ,'YData',app.model.theory.patch_data.T1) ;
                    if(isfield(objects,'p2') && isfield(app.model.theory.patch_data,'S2'))
                        set(objects.p2   ,'XData',app.model.theory.patch_data.S2) ;
                        set(objects.p2   ,'YData',app.model.theory.patch_data.T2) ;
                    end % if
                    set(objects.p3       ,'XData',app.model.theory.patch_data.S3) ;
                    set(objects.p3       ,'YData',app.model.theory.patch_data.T3) ;
                    
                    % Update gauges in real time
                    app.AWtemperatureoCGauge.Value = app.model.dynamic_parameters.T_1 ;
                    app.AWsalinitygkgGauge.Value = app.model.dynamic_parameters.S_1 ;
                    app.PWsalinitygkgGauge.Value = app.model.dynamic_parameters.S_2 ;
                    app.aWmixingfractionGauge.Value = app.model.dynamic_parameters.phi ;
                    app.aWtemperatureoCGauge.Value = app.model.dynamic_parameters.T_a ;
                    app.aWsalinitygkgGauge.Value = app.model.dynamic_parameters.S_a ;
                    app.NnormalizedGauge.Value = app.model.theory.Nstar_norm ;
                end
            end
            
        end
        
        function app = load_model(app)
            % Load dynamic data for this model and recompute as necessary.
            [app.model.base_case,app.model.dynamic_parameters,app.model.static_parameters] = define_POC_base_case(app.SelectDropDown.Value,app.model.static_parameters,app.model.geophysical_parameters,app.model.static_fns) ;
            app.model.base_case.app_mode = 1 ;      % Ensures that compute_POC_solutions doesn't save results to disk.
            [app.model.sens,app.model.vals] = compute_POC_solutions(app.model.base_case,app.model.dynamic_parameters,app.model.static_parameters,app.model.geophysical_parameters,app.model.static_fns) ;
            app.model.theory = compute_POC_theory(app.model.dynamic_parameters,app.model.static_parameters,app.model.geophysical_parameters,app.model.static_fns) ;
            
            app.model.plot_options.diag_level = 1 ;
            app.solution = 1 ;
            app.recompute = 0 ;
            app.model.dynamic_parameters.original_S_2 = compute_max_PW_salinity(app.model.dynamic_parameters.T_1,app.model.dynamic_parameters.S_1,app.model.geophysical_parameters,app.model.static_parameters,app.model.static_fns) ;
        end
        
    end
    
    methods (Access = private)
        
        function Update_TS_figure(app)
            figure(app.figs.TS)
            clf
            plot_interactive_TS_diagram(app) ;
        end
        
        function Update_fluxes_figure(app)
            figure(app.figs.results)
            clf
            plot_POC_solution(app.model)
        end
        
        function Update_after_slider_change(app)
            switch app.model.static_fns.EOS_flag
                case 'TEOS-10'
                    app.model.static_fns.alpha      = app.model.static_fns.TEOS10.alpha ;
                    app.model.static_fns.beta       = app.model.static_fns.TEOS10.beta ;
                    app.model.static_fns.rho        = app.model.static_fns.TEOS10.rho ;
                    app.model.static_fns.S_from_rho = app.model.static_fns.TEOS10.S_from_rho ;
                case 'Linear'
                    app.model.static_fns.alpha      = app.model.static_fns.linear.alpha ;
                    app.model.static_fns.beta       = app.model.static_fns.linear.beta ;
                    app.model.static_fns.rho        = app.model.static_fns.linear.rho ;
                    app.model.static_fns.S_from_rho = app.model.static_fns.linear.S_from_rho ;
            end % switch
            
            switch app.model.static_fns.T_freezing_flag
                case 'TEOS-10'
                    app.model.static_fns.T_freezing = app.model.static_fns.TEOS10.T_freezing ;
                case 'Constant'
                    app.model.static_fns.T_freezing = app.model.static_fns.constant.T_freezing ;
            end % switch
            
            [app.model.base_case,app.model.dynamic_parameters,app.model.static_parameters] = define_POC_base_case(app.SelectDropDown.Value,app.model.static_parameters,app.model.geophysical_parameters,app.model.static_fns) ;
            app.model.base_case.app_mode = 1 ;      % Ensures that compute_POC_solutions doesn't save results to disk.
            app.model.theory = compute_POC_theory(app.model.dynamic_parameters,app.model.static_parameters,app.model.geophysical_parameters,app.model.static_fns) ;
            app.model.dynamic_parameters.original_S_2 = compute_max_PW_salinity(app.model.dynamic_parameters.T_1,app.model.dynamic_parameters.S_1,app.model.geophysical_parameters,app.model.static_parameters,app.model.static_fns) ;
            app.recompute = 1 ;
            app.model.base_case.app.replot_TS_diagram = true ;
            app.SolutionLamp.Color = [1 0.5 0] ;
            Update_TS_figure(app) ;
            app.NnormalizedGauge.Value = app.model.theory.Nstar_norm ;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            absPath = fileparts(mfilename('fullpath')); % absolute path to the folder containing the mlapp file
            path(path,absPath) ;

            % Initialize static model structures
            [app.model.geophysical_parameters, app.model.static_parameters, app.model.static_fns] = define_POC_static_parameters ;
            app.model.plot_options = define_POC_plot_options ;
            
            % Load model and build model data structure.
            app = load_model(app) ;
            
            % Start display
            close all
            app.figs.TS = figure('Name','Interactive TS Diagram','units','normalized','Position',[0.4062 0.5326 0.2543 0.4000]) ;
            app.figs.results = figure('Name','Flux Results Diagram','units','normalized','Position',[0.6609 0.2674 0.3316 0.6660]) ;
            
            % Initial display of results
            Update_display(app) ;
            
            % Move uifigure
            app.ConceptualPOCModelUIFigure.Position = [17 767 1021 629] ;
        end

        % Value changed function: SelectDropDown
        function SelectDropDownValueChanged(app, event)
            % Select default experiments
            % Initialize static model structures
            [app.model.geophysical_parameters, app.model.static_parameters, app.model.static_fns] = define_POC_static_parameters ;
            app.model.plot_options = define_POC_plot_options ;
            
            % Load model and build model data structure.
            app = load_model(app) ;
            app.model.base_case.app.replot_TS_diagram = true ;
            Update_display(app) ;
        end

        % Button pushed function: SolvemodelButton
        function SolvemodelButtonPushed(app, event)
            % Prepare
            app.SolvemodelButton.BackgroundColor = 'red' ;
            app.model.base_case.suffix = 'interactive' ;
            drawnow
            app.model.base_case.force_recompute = 1 ;
            app.model.dynamic_parameters.Q = app.HeatlossTWSlider.Value*1e12 ;
            app.model.dynamic_parameters.F = app.FreshwaterlossmSvSlider.Value*1e6 ;
            app.model.dynamic_parameters.U_1 = app.AWtransportSvSlider.Value*1e6 ;
            app.model.dynamic_parameters.T_1 = app.AWtemperatureoCGauge.Value ;
            app.model.dynamic_parameters.S_1 = app.AWsalinitygkgGauge.Value ;
            app.model.dynamic_parameters.S_2 = app.PWsalinitygkgGauge.Value ;
            app.model.dynamic_parameters.phi = app.aWmixingfractionGauge.Value ;
            app.model.dynamic_parameters.T_a = app.aWtemperatureoCGauge.Value ;
            app.model.dynamic_parameters.S_a = app.aWsalinitygkgGauge.Value ;
            
            % Compute other dynamic parameters:
            app.model.dynamic_parameters.rha = app.model.static_fns.rho(app.model.dynamic_parameters.S_a,app.model.dynamic_parameters.T_a,app.model.geophysical_parameters.pressure) ;
            app.model.dynamic_parameters.Tf2 = app.model.static_fns.T_freezing(app.model.dynamic_parameters.S_2,app.model.geophysical_parameters.pressure) ;
            app.model.dynamic_parameters.Lp2 = app.model.static_parameters.L - app.model.static_parameters.c_p.*app.model.dynamic_parameters.Tf2 + app.model.static_parameters.c_i.*(app.model.dynamic_parameters.Tf2 - app.model.static_parameters.T_i) ;
            
            % Update base_case parameters
            app.model.base_case.Q = app.model.dynamic_parameters.Q ;
            app.model.base_case.F = app.model.dynamic_parameters.F ;
            app.model.base_case.U_1 = app.model.dynamic_parameters.U_1 ;
            app.model.base_case.T_1 = app.model.dynamic_parameters.T_1 ;
            app.model.base_case.S_1 = app.model.dynamic_parameters.S_1 ;
            
            % Compute numerical solution.
            [app.model.sens,app.model.vals] = compute_POC_solutions(app.model.base_case,app.model.dynamic_parameters,app.model.static_parameters,app.model.geophysical_parameters,app.model.static_fns) ;
            if(isnan(app.model.sens.Phican))
                app.solution = 0 ;
            else
                app.solution = 1 ;
                app.recompute = 0 ;
            end % if
            
            % Update
            app.model.base_case.app.replot_TS_diagram = true ;
            Update_display(app) ;
            app.SolvemodelButton.BackgroundColor = 'green' ;
            
        end

        % Value changing function: AWtransportSvSlider
        function AWtransportSvSliderValueChanging(app, event)
            changingValue = event.Value*1e6 ;
            app.model.dynamic_parameters.U_1 = changingValue ;
            app.model.base_case.U_1 = changingValue ;
            Update_after_slider_change(app) ;
        end

        % Value changing function: HeatlossTWSlider
        function HeatlossTWSliderValueChanging(app, event)
            changingValue = event.Value*1e12 ;
            app.model.dynamic_parameters.Q = changingValue ;
            app.model.base_case.Q = changingValue ;
            Update_after_slider_change(app) ;
        end

        % Value changing function: FreshwaterlossmSvSlider
        function FreshwaterlossmSvSliderValueChanging(app, event)
            changingValue = event.Value*1e6;
            app.model.dynamic_parameters.F = changingValue ;
            app.model.base_case.F = changingValue ;
            Update_after_slider_change(app) ;
        end

        % Button pushed function: ReportresultsButton
        function ReportresultsButtonPushed(app, event)
            report_POC_results(app.model) ;
            assignin('base','model',app.model) ;
%            Saving is experimental (it works, but loading again is tricky).
%             interactive_model = app.model ;
%             save_filename  = ['results/POC_results_Interactive.mat'] ;
%             save(save_filename,'interactive_model')
%             fprintf(' Saved model solution to [%s].\n\n',save_filename) ;
        end

        % Value changed function: FreezingtemperatureSwitch
        function FreezingtemperatureSwitchValueChanged(app, event)
            app.model.static_fns.T_freezing_flag = app.FreezingtemperatureSwitch.Value ;
            Update_after_slider_change(app) ;
        end

        % Value changed function: EquationofstateSwitch
        function EquationofstateSwitchValueChanged(app, event)
            app.model.static_fns.EOS_flag = app.EquationofstateSwitch.Value;
            Update_after_slider_change(app) ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConceptualPOCModelUIFigure = uifigure('Visible', 'off');
            app.ConceptualPOCModelUIFigure.Name = 'Conceptual POC Model';
            app.AWtemperatureoCGaugeLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.AWtemperatureoCGaugeLabel.Text = 'AW temperature [oC]';
            app.AWtemperatureoCGauge = uigauge(app.ConceptualPOCModelUIFigure, 'circular');
            app.AWtemperatureoCGauge.Limits = [-2 5];
            app.AWtemperatureoCGauge.Tooltip = {'Atlantic Water temperature defined from the interactive TS diagram.'};
            app.SelectDropDownLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.SelectDropDownLabel.Text = '1. Select:';
            app.SelectDropDown = uidropdown(app.ConceptualPOCModelUIFigure);
            app.SelectDropDown.Items = {'Exp. 1: Arctic', 'Exp. 2: Arctic high Q', 'Exp. 6: Antarctic', 'Exp. V: Antarctic warm AW', 'Exp. W: Arctic near Eldevik & Nilsen 2013', 'Exp. X: Arctic intermediate Q', 'Exp. Y: Arctic small PW S'};
            app.SelectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDropDownValueChanged, true);
            app.SelectDropDown.Tooltip = {'Pick the base experiment from these pre-defined options.'};
            app.SelectDropDown.Value = 'Exp. 1: Arctic';
            app.AWsalinitygkgGaugeLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.AWsalinitygkgGaugeLabel.Text = 'AW salinity [g/kg]';
            app.AWsalinitygkgGauge = uigauge(app.ConceptualPOCModelUIFigure, 'circular');
            app.AWsalinitygkgGauge.Limits = [34 36];
            app.AWsalinitygkgGauge.MajorTicks = [34 34.5 35 35.5 36];
            app.AWsalinitygkgGauge.Tooltip = {'Atlantic Water salinity defined from the interactive TS diagram.'};
            app.SolvemodelButton = uibutton(app.ConceptualPOCModelUIFigure, 'push');
            app.SolvemodelButton.ButtonPushedFcn = createCallbackFcn(app, @SolvemodelButtonPushed, true);
            app.SolvemodelButton.Tooltip = {'Push button to compute the numerical solution.'};
            app.SolvemodelButton.Text = 'Solve model';
            app.EntrainmentGaugeLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.EntrainmentGaugeLabel.Text = 'Entrainment';
            app.EntrainmentGauge = uigauge(app.ConceptualPOCModelUIFigure, 'semicircular');
            app.EntrainmentGauge.Limits = [0 1];
            app.EntrainmentGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.EntrainmentGauge.Tooltip = {'Entrainment fraction (canonical value) of ambient Water and overflowing Shelf Water that forms Overflow Water.'};
            app.SolutionLampLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.SolutionLampLabel.Tooltip = {'Push button to compute the numerical solution.'};
            app.SolutionLampLabel.Text = 'Solution';
            app.SolutionLamp = uilamp(app.ConceptualPOCModelUIFigure);
            app.SolutionLamp.Tooltip = {'Numerical solution status: '; 'Green = Done'; 'Orange = Pending'; 'Red = No solution.'};
            app.PWsalinitygkgGaugeLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.PWsalinitygkgGaugeLabel.Text = 'PW salinity [g/kg]';
            app.PWsalinitygkgGauge = uigauge(app.ConceptualPOCModelUIFigure, 'linear');
            app.PWsalinitygkgGauge.Limits = [32 35];
            app.PWsalinitygkgGauge.MajorTicks = [30 30.5 31 31.5 32 32.5 33 33.5 34 34.5 35 35.5 36];
            app.PWsalinitygkgGauge.Tooltip = {'Polar Water salinity set by the interactive TS diagram.'};
            app.aWsalinitygkgGaugeLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.aWsalinitygkgGaugeLabel.Text = 'aW salinity [g/kg]';
            app.aWsalinitygkgGauge = uigauge(app.ConceptualPOCModelUIFigure, 'circular');
            app.aWsalinitygkgGauge.Limits = [34 36];
            app.aWsalinitygkgGauge.MajorTicks = [34 34.5 35 35.5 36];
            app.aWsalinitygkgGauge.Tooltip = {'Ambient Water salinity defined from the interactive TS diagram.'};
            app.aWtemperatureoCGaugeLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.aWtemperatureoCGaugeLabel.Text = 'aW temperature [oC]';
            app.aWtemperatureoCGauge = uigauge(app.ConceptualPOCModelUIFigure, 'circular');
            app.aWtemperatureoCGauge.Limits = [-2 5];
            app.aWtemperatureoCGauge.Tooltip = {'Ambient Water temperature defined from the interactive TS diagram.'};
            app.aWmixingfractionGaugeLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.aWmixingfractionGaugeLabel.Text = 'aW mixing fraction';
            app.aWmixingfractionGauge = uigauge(app.ConceptualPOCModelUIFigure, 'ninetydegree');
            app.aWmixingfractionGauge.Limits = [0 1];
            app.aWmixingfractionGauge.Tooltip = {'Mixing fraction of Polar Water and Atlantic Water to form ambient Water. Computed from the interactive TS diagram.'};
            app.SWsalinitygkgGaugeLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.SWsalinitygkgGaugeLabel.Text = 'SW salinity [g/kg]';
            app.SWsalinitygkgGauge = uigauge(app.ConceptualPOCModelUIFigure, 'linear');
            app.SWsalinitygkgGauge.Limits = [34 40];
            app.SWsalinitygkgGauge.MajorTicks = [30 31 32 33 34 35 36 37 38 39 40];
            app.SWsalinitygkgGauge.Tooltip = {'Shelf Water salinity (canonical value).'};
            app.SelectParametersLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.SelectParametersLabel.Text = 'Select Parameters';
            app.ResultsLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.ResultsLabel.Text = 'Results';
            app.ConceptualPolarOverturningCirculationModelLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.ConceptualPolarOverturningCirculationModelLabel.Text = 'Conceptual Polar Overturning Circulation Model';
            app.SelectAWaWandPWpropertiesontheinteractiveTSdiagramLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.SelectAWaWandPWpropertiesontheinteractiveTSdiagramLabel.Text = {'3. Select AW aW and PW properties on the'; 'interactive TS diagram.'};
            app.OWtemperatureoCGauge = uigauge(app.ConceptualPOCModelUIFigure, 'circular');
            app.OWtemperatureoCGauge.Limits = [-2 5];
            app.OWtemperatureoCGauge.Tooltip = {'Overflow Water temperature (canonical value).'};
            app.OWtemperatureoCGaugeLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.OWtemperatureoCGaugeLabel.Text = 'OW temperature [oC]';
            app.OWsalinitygkgGauge = uigauge(app.ConceptualPOCModelUIFigure, 'circular');
            app.OWsalinitygkgGauge.Limits = [34 36];
            app.OWsalinitygkgGauge.MajorTicks = [34 34.5 35 35.5 36];
            app.OWsalinitygkgGauge.Tooltip = {'Overflow Water salinity (canonical value).'};
            app.AWsalinitygkgGaugeLabel_2 = uilabel(app.ConceptualPOCModelUIFigure);
            app.AWsalinitygkgGaugeLabel_2.Text = 'OW salinity [g/kg]';
            app.SeeFluxResultsDiagramLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.SeeFluxResultsDiagramLabel.Text = {'See:'; 'Flux Results Diagram'};
            app.NnormalizedGaugeLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.NnormalizedGaugeLabel.Text = 'N* [normalized]';
            app.NnormalizedGauge = uigauge(app.ConceptualPOCModelUIFigure, 'linear');
            app.NnormalizedGauge.Limits = [-1 6];
            app.NnormalizedGauge.MajorTicks = [-1 0 1 2 3 4 5 6];
            app.NnormalizedGauge.MajorTickLabels = {'-1', '0', '1', '2', '3', '4', '5', '6'};
            app.NnormalizedGauge.Tooltip = {'Compound normalized forcing variable N*. Depends on U1'; ' Q'; ' F.'};
            app.ReportresultsButton = uibutton(app.ConceptualPOCModelUIFigure, 'push');
            app.ReportresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ReportresultsButtonPushed, true);
            app.ReportresultsButton.Tooltip = {'Push button to report results to command line and save to disk.'};
            app.ReportresultsButton.Text = 'Report results';
            app.TabGroup = uitabgroup(app.ConceptualPOCModelUIFigure);
            app.ForcingparametersTab = uitab(app.TabGroup);
            app.ForcingparametersTab.Title = 'Forcing parameters';
            app.FreshwaterlossmSvLabel = uilabel(app.ForcingparametersTab);
            app.FreshwaterlossmSvLabel.Text = {'Freshwater'; 'loss'; '[mSv]'};
            app.FreshwaterlossmSvSlider = uislider(app.ForcingparametersTab);
            app.FreshwaterlossmSvSlider.Limits = [-400 0];
            app.FreshwaterlossmSvSlider.MajorTicks = [-500 -400 -300 -200 -100 0];
            app.FreshwaterlossmSvSlider.MajorTickLabels = {'-500', '-400', '-300', '-200', '-100', '0'};
            app.FreshwaterlossmSvSlider.ValueChangingFcn = createCallbackFcn(app, @FreshwaterlossmSvSliderValueChanging, true);
            app.FreshwaterlossmSvSlider.Tooltip = {'Select the total freshwater loss rate from the ocean F.'};
            app.HeatlossTWLabel = uilabel(app.ForcingparametersTab);
            app.HeatlossTWLabel.Text = {'Heat'; 'loss'; '[TW]'};
            app.HeatlossTWSlider = uislider(app.ForcingparametersTab);
            app.HeatlossTWSlider.Limits = [0 500];
            app.HeatlossTWSlider.ValueChangingFcn = createCallbackFcn(app, @HeatlossTWSliderValueChanging, true);
            app.HeatlossTWSlider.Tooltip = {'Select the total heat loss rate from the ocean Q.'};
            app.AWtransportSvLabel = uilabel(app.ForcingparametersTab);
            app.AWtransportSvLabel.Text = {'AW'; 'transport'; '[Sv]'};
            app.AWtransportSvSlider = uislider(app.ForcingparametersTab);
            app.AWtransportSvSlider.Limits = [0 30];
            app.AWtransportSvSlider.ValueChangingFcn = createCallbackFcn(app, @AWtransportSvSliderValueChanging, true);
            app.AWtransportSvSlider.Tooltip = {'Select the inflowing Atlantic Water transport (volume flux).'};
            app.AWtransportSvSlider.Value = 9.86666666666667;
            app.StaticparametersTab = uitab(app.TabGroup);
            app.StaticparametersTab.Title = 'Static parameters';
            app.FreezingtemperatureSwitchLabel = uilabel(app.StaticparametersTab);
            app.FreezingtemperatureSwitchLabel.Text = 'Freezing temperature';
            app.FreezingtemperatureSwitch = uiswitch(app.StaticparametersTab, 'slider');
            app.FreezingtemperatureSwitch.Items = {'Constant', 'TEOS-10'};
            app.FreezingtemperatureSwitch.ItemsData = {'Constant', 'TEOS-10'};
            app.FreezingtemperatureSwitch.ValueChangedFcn = createCallbackFcn(app, @FreezingtemperatureSwitchValueChanged, true);
            app.FreezingtemperatureSwitch.Value = 'Constant';
            app.EquationofstateSwitchLabel = uilabel(app.StaticparametersTab);
            app.EquationofstateSwitchLabel.Text = 'Equation of state';
            app.EquationofstateSwitch = uiswitch(app.StaticparametersTab, 'slider');
            app.EquationofstateSwitch.Items = {'Linear', 'TEOS-10'};
            app.EquationofstateSwitch.ItemsData = {'Linear', 'TEOS-10'};
            app.EquationofstateSwitch.ValueChangedFcn = createCallbackFcn(app, @EquationofstateSwitchValueChanged, true);
            app.EquationofstateSwitch.Value = 'Linear';
            app.SelectLabel = uilabel(app.ConceptualPOCModelUIFigure);
            app.SelectLabel.Text = {'2. Select model parameters: Watch constraints '; 'change on the interactive TS diagram.'};
            % Show the figure after all components are created
            app.ConceptualPOCModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interactive_POC_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConceptualPOCModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConceptualPOCModelUIFigure)
        end
    end
end